classdef Labelization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        saveFileNameLabel          matlab.ui.control.Label
        CompletedCheckBox          matlab.ui.control.CheckBox
        mapFileNameLabel           matlab.ui.control.Label
        loadButton                 matlab.ui.control.Button
        exportButton               matlab.ui.control.Button
        Label_10                   matlab.ui.control.Label
        Label_9                    matlab.ui.control.Label
        Label_8                    matlab.ui.control.Label
        Label_7                    matlab.ui.control.Label
        Label_6                    matlab.ui.control.Label
        Label_5                    matlab.ui.control.Label
        Label_4                    matlab.ui.control.Label
        Label_3                    matlab.ui.control.Label
        Label_2                    matlab.ui.control.Label
        Label                      matlab.ui.control.Label
        ImgTemplate32              matlab.ui.control.Image
        ImgTemplate31              matlab.ui.control.Image
        ImgTemplate21              matlab.ui.control.Image
        ImgTemplate30              matlab.ui.control.Image
        ImgTemplate20              matlab.ui.control.Image
        ImgTemplate10              matlab.ui.control.Image
        ImgTemplatex3              matlab.ui.control.Image
        ImgTemplatex2              matlab.ui.control.Image
        ImgTemplatex1              matlab.ui.control.Image
        ImgTemplate00              matlab.ui.control.Image
        SpeciesNameEditField       matlab.ui.control.EditField
        SpeciesNameEditFieldLabel  matlab.ui.control.Label
        SpeciesSEQ                 matlab.ui.control.Spinner
        SpeciesIDLabel             matlab.ui.control.Label
        AxesImage                  matlab.ui.control.UIAxes
        AxesLabel                  matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        % label
        speciesName
        seq
        codeMaskSp
        completed
        mapFileName
        i_sp
        % fix
        templateCode
        template
        gX
        gY
        codeName
        %intern
        datafilepath='./data';
    end
    
    methods (Access = public)
        
        function changeSp(app,i_sp)
            app.i_sp=i_sp;
            app.SpeciesSEQ.Value=i_sp;
            app.SpeciesNameEditField.Value = app.speciesName(i_sp);
            app.mapFileNameLabel.Text = app.mapFileName(i_sp);
            app.CompletedCheckBox.Value = app.completed(i_sp);


            % colormap(app.AxesImage,"gray")

            % Label
            clf(app.AxesLabel)
            hold(app.AxesLabel, 'on' )
            % imagesc(app.AxesLabel,repmat(app.template,1,1,3)./255);
            id_block = find(app.codeMaskSp(:,i_sp)~=1);
            x_template = 1:size(app.templateCode,1); x_template=(x_template-mean(x_template))+.5;
            y_template = 1:size(app.templateCode,2); y_template=(y_template-mean(y_template))+.5;
            map_b = app.template;
            for i_b=1:numel(id_block)
                %val = app.templateCode(:,:,app.codeMaskSp(id_block(i_b),i_sp));
                %val = repmat(val,1,1,3)./255;
                % imagesc(app.AxesLabel,app.gX(id_block(i_b))+x_template,app.gY(id_block(i_b))+y_template,val)
                map_b(app.gY(id_block(i_b))+y_template, app.gX(id_block(i_b))+x_template) = app.templateCode(:,:,app.codeMaskSp(id_block(i_b),i_sp));
            end
            imagesc(app.AxesLabel,repmat(map_b,1,1,3)./255)

            if (app.mapFileName(i_sp)=="")
                mapImSp = imread("template_mask.png");
                imagesc(app.AxesImage, mapImSp)
            else
                mapImSp = imread("extract/"+ app.mapFileName(i_sp));
                imagesc(app.AxesImage, repmat(mapImSp,1,1,3))

%                 if app.DisplayuncertaintyCheckBox.Value
%                     err = abs(map_b-double(mapImSp));
%                     err2 = conv2(err,1/28^2*ones(28),'same');
%                     codeMaskSpUncer = err2(sub2ind(size(err),app.gY,app.gX));
%                     scatter(app.AxesLabel,app.gX,app.gY,550,codeMaskSpUncer,'s','linewidth',4)
%                 end
            end
        end

        function changeCode(app,code)
            roiPOC = drawpoint(app.AxesLabel); 
            PosPOC=get(roiPOC,'Position');
            [~,id_c]=min(sqrt((app.gX-PosPOC(1)).^2+(app.gY-PosPOC(2)).^2));
            app.codeMaskSp(id_c,app.i_sp)=code;
            changeSp(app,app.i_sp)
        end
        
        function loadFile(app,filename)
            load(filename);
            app.speciesName = speciesName;
            app.seq = seq;
            app.codeMaskSp = codeMaskSp;
            app.completed = completed;
            app.mapFileName = mapFileName;
            changeSp(app,i_sp)
        end
        
        function saveFile(app,msg)
            
            speciesName = app.speciesName;
            seq = app.seq;
            codeMaskSp = app.codeMaskSp;
            completed = app.completed;
            i_sp = app.i_sp;
            mapFileName = app.mapFileName;
            
            filename=['data_label_v1_' datestr(datetime,'yyyymmdd-HHMMSS') '.mat'];
            app.saveFileNameLabel.Text=filename;
            save([app.datafilepath '/' filename],'speciesName','seq','codeMaskSp','completed','i_sp','mapFileName')
            if msg
                msgbox(['File ' filename ' exported succesfully. You can keep working with the current version and can load this file later'],"Success","modal")
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            if isempty(dir('data'))
                [filename, app.datafilepath] = uigetfile({'*.mat'},'Select data_fix.mat');
            else
                filename='data_fix';
            end
            
            load([app.datafilepath '/' filename])
            app.templateCode = templateCode;
            app.template = template;
            app.gX = gX;
            app.gY = gY;
            app.codeName = codeName;

            fl = dir(app.datafilepath);
            fl = fl(contains({fl.name},'data_label'));
            [~,id] = max(datetime({fl.date}));
            [fl(id).folder '/' fl(id).name]
            loadFile(app,[fl(id).folder '/' fl(id).name])

            app.saveFileNameLabel.Text=fl(id).name;
        end

        % Value changed function: SpeciesSEQ
        function SpeciesSEQValueChanged(app, event)
            changeSp(app,app.SpeciesSEQ.Value)
        end

        % Value changed function: SpeciesNameEditField
        function SpeciesNameEditFieldValueChanged(app, event)
            app.speciesName(app.i_sp) = app.SpeciesNameEditField.Value;
        end

        % Image clicked function: ImgTemplate00
        function ImgTemplate00ImageClicked(app, event)
            changeCode(app,1)
        end

        % Callback function
        function LewisCodeEditFieldValueChanged(app, event)
            app.lewisCode(app.i_sp) = app.LewisCodeEditField.Value;
        end

        % Image clicked function: ImgTemplatex1
        function ImgTemplatex1ImageClicked(app, event)
            changeCode(app,8)
        end

        % Image clicked function: ImgTemplatex2
        function ImgTemplatex2ImageClicked(app, event)
            changeCode(app,9)
        end

        % Image clicked function: ImgTemplatex3
        function ImgTemplatex3ImageClicked(app, event)
            changeCode(app,10)
        end

        % Image clicked function: ImgTemplate10
        function ImgTemplate10ImageClicked(app, event)
            changeCode(app,2)
        end

        % Image clicked function: ImgTemplate20
        function ImgTemplate20ImageClicked(app, event)
            changeCode(app,3)
        end

        % Image clicked function: ImgTemplate30
        function ImgTemplate30ImageClicked(app, event)
            changeCode(app,5)
        end

        % Image clicked function: ImgTemplate21
        function ImgTemplate21ImageClicked(app, event)
            changeCode(app,4)
        end

        % Image clicked function: ImgTemplate31
        function ImgTemplate31ImageClicked(app, event)
            changeCode(app,6)
        end

        % Image clicked function: ImgTemplate32
        function ImgTemplate32ImageClicked(app, event)
            changeCode(app,7)
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
        key = event.Key;
        switch key
            case '1'
                code = 1;
            case '2'
                code = 8;
            case '3'
                code = 9;
            case '4'
                code = 10;
            case '5'
                code = 2;
            case '6'
                code = 3;
            case '7'
                code = 5;
            case '8'
                code = 4;
            case '9'
                code = 6;
            case '0'
                code = 7;
            otherwise
                return
        end
        changeCode(app,code)
        end

        % Button pushed function: loadButton
        function loadButtonPushed(app, event)
            [filename, filepath] = uigetfile({'*.mat'},'Load the mat file');
            loadFile(app,[filepath filename])
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
            saveFile(app,true)
        end

        % Value changed function: CompletedCheckBox
        function CompletedCheckBoxValueChanged(app, event)
            app.completed(app.i_sp)=app.CompletedCheckBox.Value;
            saveFile(app,false)
        end

        % Callback function
        function DisplayUncertaintyCheckBoxValueChanged(app, event)
      
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.AxesLabel = uiaxes(app.UIFigure);
            app.AxesLabel.YDir = 'reverse';
            app.AxesLabel.XTick = [];
            app.AxesLabel.YTick = [];
            app.AxesImage = uiaxes(app.UIFigure);
            app.AxesImage.XTick = [];
            app.AxesImage.YTick = [];
            app.AxesImage.ZTick = [];
            app.SpeciesIDLabel = uilabel(app.UIFigure);
            app.SpeciesIDLabel.Text = 'SEQ';
            app.SpeciesSEQ = uispinner(app.UIFigure);
            app.SpeciesSEQ.Limits = [1 Inf];
            app.SpeciesSEQ.ValueChangedFcn = createCallbackFcn(app, @SpeciesSEQValueChanged, true);
            app.SpeciesSEQ.Value = 1;
            app.SpeciesNameEditFieldLabel = uilabel(app.UIFigure);
            app.SpeciesNameEditFieldLabel.Text = 'Species Name';
            app.SpeciesNameEditField = uieditfield(app.UIFigure, 'text');
            app.SpeciesNameEditField.ValueChangedFcn = createCallbackFcn(app, @SpeciesNameEditFieldValueChanged, true);
            app.SpeciesNameEditField.Editable = 'off';
            app.ImgTemplate00 = uiimage(app.UIFigure);
            app.ImgTemplate00.ImageClickedFcn = createCallbackFcn(app, @ImgTemplate00ImageClicked, true);
            app.ImgTemplate00.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_00.png');
            app.ImgTemplatex1 = uiimage(app.UIFigure);
            app.ImgTemplatex1.ImageClickedFcn = createCallbackFcn(app, @ImgTemplatex1ImageClicked, true);
            app.ImgTemplatex1.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_x1.png');
            app.ImgTemplatex2 = uiimage(app.UIFigure);
            app.ImgTemplatex2.ImageClickedFcn = createCallbackFcn(app, @ImgTemplatex2ImageClicked, true);
            app.ImgTemplatex2.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_x2.png');
            app.ImgTemplatex3 = uiimage(app.UIFigure);
            app.ImgTemplatex3.ImageClickedFcn = createCallbackFcn(app, @ImgTemplatex3ImageClicked, true);
            app.ImgTemplatex3.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_x3.png');
            app.ImgTemplate10 = uiimage(app.UIFigure);
            app.ImgTemplate10.ImageClickedFcn = createCallbackFcn(app, @ImgTemplate10ImageClicked, true);
            app.ImgTemplate10.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_10.png');
            app.ImgTemplate20 = uiimage(app.UIFigure);
            app.ImgTemplate20.ImageClickedFcn = createCallbackFcn(app, @ImgTemplate20ImageClicked, true);
            app.ImgTemplate20.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_20.png');
            app.ImgTemplate30 = uiimage(app.UIFigure);
            app.ImgTemplate30.ImageClickedFcn = createCallbackFcn(app, @ImgTemplate30ImageClicked, true);
            app.ImgTemplate30.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_30.png');
            app.ImgTemplate21 = uiimage(app.UIFigure);
            app.ImgTemplate21.ImageClickedFcn = createCallbackFcn(app, @ImgTemplate21ImageClicked, true);
            app.ImgTemplate21.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_21.png');
            app.ImgTemplate31 = uiimage(app.UIFigure);
            app.ImgTemplate31.ImageClickedFcn = createCallbackFcn(app, @ImgTemplate31ImageClicked, true);
            app.ImgTemplate31.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_31.png');
            app.ImgTemplate32 = uiimage(app.UIFigure);
            app.ImgTemplate32.ImageClickedFcn = createCallbackFcn(app, @ImgTemplate32ImageClicked, true);
            app.ImgTemplate32.ImageSource = fullfile(pathToMLAPP, 'templateCode', 'templateCode_32.png');
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '1';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = '2';
            app.Label_3 = uilabel(app.UIFigure);
            app.Label_3.Text = '3';
            app.Label_4 = uilabel(app.UIFigure);
            app.Label_4.Text = '4';
            app.Label_5 = uilabel(app.UIFigure);
            app.Label_5.Text = '5';
            app.Label_6 = uilabel(app.UIFigure);
            app.Label_6.Text = '6';
            app.Label_7 = uilabel(app.UIFigure);
            app.Label_7.Text = '7';
            app.Label_8 = uilabel(app.UIFigure);
            app.Label_8.Text = '8';
            app.Label_9 = uilabel(app.UIFigure);
            app.Label_9.Text = '9';
            app.Label_10 = uilabel(app.UIFigure);
            app.Label_10.Text = '0';
            app.exportButton = uibutton(app.UIFigure, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'export';
            app.loadButton = uibutton(app.UIFigure, 'push');
            app.loadButton.ButtonPushedFcn = createCallbackFcn(app, @loadButtonPushed, true);
            app.loadButton.Text = 'load';
            app.mapFileNameLabel = uilabel(app.UIFigure);
            app.mapFileNameLabel.Text = 'A_bird_atlas_of_kenya_Page_';
            app.CompletedCheckBox = uicheckbox(app.UIFigure);
            app.CompletedCheckBox.ValueChangedFcn = createCallbackFcn(app, @CompletedCheckBoxValueChanged, true);
            app.CompletedCheckBox.Text = 'Completed';
            app.saveFileNameLabel = uilabel(app.UIFigure);
            app.saveFileNameLabel.Text = 'filename';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Labelization

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end